classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout_main        matlab.ui.container.GridLayout
        GridLayout_axes        matlab.ui.container.GridLayout
        GridLayout_axes_menu   matlab.ui.container.GridLayout
        Slider                 matlab.ui.control.Slider
        TextArea               matlab.ui.control.TextArea
        GridLayout             matlab.ui.container.GridLayout
        CorrienteexternaLabel  matlab.ui.control.Label
        ValorIap               matlab.ui.control.NumericEditField
        gbarNaEditFieldLabel   matlab.ui.control.Label
        ValorgbarNa            matlab.ui.control.NumericEditField
        Slider_2               matlab.ui.control.Slider
        Tabs4                  matlab.ui.container.TabGroup
        Tab_2                  matlab.ui.container.Tab
        GridLayout4            matlab.ui.container.GridLayout
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        Tab2_2                 matlab.ui.container.Tab
        GridLayout5            matlab.ui.container.GridLayout
        UIAxes4                matlab.ui.control.UIAxes
        Tab3                   matlab.ui.container.Tab
        GridLayout6            matlab.ui.container.GridLayout
        UIAxes5                matlab.ui.control.UIAxes
        Tab4                   matlab.ui.container.Tab
        GridLayout7            matlab.ui.container.GridLayout
        UIAxes6                matlab.ui.control.UIAxes
        Tabs1y2                matlab.ui.container.TabGroup
        Voltage                matlab.ui.container.Tab
        GridLayout2            matlab.ui.container.GridLayout
        UIAxes_tab1            matlab.ui.control.UIAxes
        Ion_plot               matlab.ui.container.Tab
        GridLayout3            matlab.ui.container.GridLayout
        UIAxes_tab2            matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        MenuPanel              matlab.ui.container.Panel
        GridLayout_menu        matlab.ui.container.GridLayout
        btn_ej1                matlab.ui.control.StateButton
        btn_ej2                matlab.ui.control.StateButton
        btn_ej3                matlab.ui.control.StateButton
        btn_ej4                matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: btn_ej1
        function btn_1(app, event)
            if app.btn_ej1.Value==1
                app.Tabs1y2.Visible='on';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='off';
                [t,V,ion]=mainHH_Euler_DNI();
                plot(app.UIAxes_tab1,t,V)
                plot(app.UIAxes_tab2,t(1:length(ion.INa)),ion.INa, ...
                    'g',t(1:length(ion.INa)),ion.IK,'r', ...
                    t(1:length(ion.INa)),ion.Il,'k','LineWidth',2)
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'on';
                app.Slider.Value=0.1;
                app.ValorIap.Value=app.Slider.Value;
                app.ValorgbarNa.Value=0;
                app.ValorIap.Enable='on';
                app.ValorgbarNa.Enable='off';
                %Disable other btn
                app.btn_ej2.Value=0;
                app.btn_ej3.Value=0;
                app.btn_ej4.Value=0;
            else
                cla(app.UIAxes)
                cla(app.UIAxes_tab1)
                cla(app.UIAxes_tab2)
                app.Tabs1y2.Visible='off';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='off';
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.ValorIap.Enable='off';
                app.ValorgbarNa.Enable='off';
                app.ValorgbarNa.Value=0;
                app.ValorIap.Value=0;
            end
        end

        % Value changing function: Slider
        function Iap_slider(app, event)
            changingValue = event.Value;
            app.ValorIap.Value=changingValue;
            [t,V,ion]=mainHH_Euler_DNI(changingValue);
            plot(app.UIAxes_tab1,t,V)
            plot(app.UIAxes_tab2,t(1:length(ion.INa)),ion.INa, ...
                'g',t(1:length(ion.INa)),ion.IK,'r', ...
                t(1:length(ion.INa)),ion.Il,'k','LineWidth',2)
        end

        % Value changed function: btn_ej2
        function btn_2(app, event)
            if app.btn_ej2.Value==1
                app.Slider.Value=0.5;
                app.ValorgbarNa.Value=0;
                app.Tabs1y2.Visible='on';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='off';
                [t,V,ion]=mainHH_Euler_DNI(app.Slider.Value);
                plot(app.UIAxes_tab1,t,V)
                plot(app.UIAxes_tab2,t(1:length(ion.INa)),ion.INa, ...
                    'g',t(1:length(ion.INa)),ion.IK,'r', ...
                    t(1:length(ion.INa)),ion.Il,'k','LineWidth',2)
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'on';
                app.ValorIap.Value=app.Slider.Value;
                app.ValorIap.Enable='on';
                app.ValorgbarNa.Enable='off';
                %Disable other btn
                app.btn_ej1.Value=0;
                app.btn_ej3.Value=0;
                app.btn_ej4.Value=0;
            else
                cla(app.UIAxes)
                cla(app.UIAxes_tab1)
                cla(app.UIAxes_tab2)
                app.Tabs1y2.Visible='off';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='off';
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.ValorIap.Enable='off';
                app.ValorgbarNa.Enable='off';
                app.ValorgbarNa.Value=0;
                app.ValorIap.Value=0;
            end
        end

        % Value changed function: btn_ej3
        function btn_3(app, event)
            if app.btn_ej3.Value==1
                cla(app.UIAxes)
                app.Tabs1y2.Visible='off';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='on';
                app.Slider_2.Visible = 'on';
                app.Slider.Visible = 'off';
                app.Slider_2.Value=1.2;
                app.ValorIap.Enable='off';
                app.ValorgbarNa.Enable='on';
                app.ValorgbarNa.Value=app.Slider_2.Value;
                app.ValorIap.Value=0;
                gbarNa=[(1.2*0.8) 1.2 1.2*1.2 ];
                [t,V]=Simulaalcoholycafeina_DNI(gbarNa);
                hold(app.UIAxes, 'on' )
                plot(app.UIAxes,t{1},V{1})
                plot(app.UIAxes,t{2},V{2})
                plot(app.UIAxes,t{3},V{3})
                hold(app.UIAxes, 'off' )
                %Disable other btn
                app.btn_ej1.Value=0;
                app.btn_ej2.Value=0;
                app.btn_ej4.Value=0;
            else
                cla(app.UIAxes)
                cla(app.UIAxes_tab1)
                cla(app.UIAxes_tab2)
                app.Tabs1y2.Visible='off';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='off';
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.ValorIap.Enable='off';
                app.ValorgbarNa.Enable='off';
                app.ValorgbarNa.Value=0;
                app.ValorIap.Value=0;
            end
        end

        % Value changing function: Slider_2
        function gbarNa_slider(app, event)
            changingValue = event.Value;
            app.ValorgbarNa.Value=changingValue;
            gbarNa=[(1.2*0.8) changingValue 1.2*1.2 ];
            [t,V]=Simulaalcoholycafeina_DNI(gbarNa);
            plot(app.UIAxes,t{1},V{1})
            hold(app.UIAxes, 'on' )
            plot(app.UIAxes,t{2},V{2})
            plot(app.UIAxes,t{3},V{3})
            hold(app.UIAxes, 'off' )
        end

        % Value changed function: btn_ej4
        function btn_4(app, event)
            if app.btn_ej4.Value==1
                app.Tabs1y2.Visible='off';
                app.Tabs4.Visible='on';
                app.UIAxes.Visible='off';
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.ValorIap.Enable='off';
                app.ValorgbarNa.Enable='off';
                app.ValorgbarNa.Value=0;
                app.ValorIap.Value=0;
                
                [var]=calculaelectromiogramas_DNI;
                %TAB1
                plot(app.UIAxes2,var.AP_sano), hold(app.UIAxes2,'on')
                plot(app.UIAxes2,var.AP_sano_des), hold(app.UIAxes2,'off')
                plot(app.UIAxes3,var.AP_isch), hold(app.UIAxes3,'on')
                plot(app.UIAxes3,var.AP_isch_des), hold(app.UIAxes3,'off')
                %TAB2
                plot(app.UIAxes4,var.EGM_sano)
                %TAB3
                plot(app.UIAxes5,var.EGM_ischemico)
                %TAB4
                plot(app.UIAxes6,var.EGM_transicion)
                %Disable other btn
                app.btn_ej1.Value=0;
                app.btn_ej2.Value=0;
                app.btn_ej3.Value=0;
            else
                cla(app.UIAxes)
                cla(app.UIAxes_tab1)
                cla(app.UIAxes_tab2)
                app.Tabs1y2.Visible='off';
                app.Tabs4.Visible='off';
                app.UIAxes.Visible='off';
                app.Slider_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.ValorIap.Enable='off';
                app.ValorgbarNa.Enable='off';
                app.ValorgbarNa.Value=0;
                app.ValorIap.Value=0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.GridLayout_axes = uigridlayout(app.GridLayout_main);
            app.GridLayout_axes_menu = uigridlayout(app.GridLayout_axes);
            app.Slider = uislider(app.GridLayout_axes_menu);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @Iap_slider, true);
            app.Slider.Visible = 'off';
            app.Slider.Value = 0.1;
            app.TextArea = uitextarea(app.GridLayout_axes_menu);
            app.GridLayout = uigridlayout(app.GridLayout_axes_menu);
            app.CorrienteexternaLabel = uilabel(app.GridLayout);
            app.CorrienteexternaLabel.Text = 'Corriente externa';
            app.ValorIap = uieditfield(app.GridLayout, 'numeric');
            app.ValorIap.Editable = 'off';
            app.gbarNaEditFieldLabel = uilabel(app.GridLayout);
            app.gbarNaEditFieldLabel.Text = 'gbarNa';
            app.ValorgbarNa = uieditfield(app.GridLayout, 'numeric');
            app.Slider_2 = uislider(app.GridLayout_axes_menu);
            app.Slider_2.Limits = [0.96 1.44];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @gbarNa_slider, true);
            app.Slider_2.Visible = 'off';
            app.Slider_2.Value = 1.2;
            app.Tabs4 = uitabgroup(app.GridLayout_axes);
            app.Tabs4.Visible = 'off';
            app.Tab_2 = uitab(app.Tabs4);
            app.Tab_2.Title = 'Tab';
            app.GridLayout4 = uigridlayout(app.Tab_2);
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab2_2 = uitab(app.Tabs4);
            app.Tab2_2.Title = 'Tab2';
            app.GridLayout5 = uigridlayout(app.Tab2_2);
            app.UIAxes4 = uiaxes(app.GridLayout5);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Tab3 = uitab(app.Tabs4);
            app.Tab3.Title = 'Tab3';
            app.GridLayout6 = uigridlayout(app.Tab3);
            app.UIAxes5 = uiaxes(app.GridLayout6);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Tab4 = uitab(app.Tabs4);
            app.Tab4.Title = 'Tab4';
            app.GridLayout7 = uigridlayout(app.Tab4);
            app.UIAxes6 = uiaxes(app.GridLayout7);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Tabs1y2 = uitabgroup(app.GridLayout_axes);
            app.Tabs1y2.Visible = 'off';
            app.Voltage = uitab(app.Tabs1y2);
            app.Voltage.Title = 'Voltage';
            app.GridLayout2 = uigridlayout(app.Voltage);
            app.UIAxes_tab1 = uiaxes(app.GridLayout2);
            title(app.UIAxes_tab1, 'Title')
            xlabel(app.UIAxes_tab1, 'X')
            ylabel(app.UIAxes_tab1, 'Y')
            zlabel(app.UIAxes_tab1, 'Z')
            app.Ion_plot = uitab(app.Tabs1y2);
            app.Ion_plot.Title = 'Ion channel currents';
            app.GridLayout3 = uigridlayout(app.Ion_plot);
            app.UIAxes_tab2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_tab2, 'Title')
            xlabel(app.UIAxes_tab2, 'X')
            ylabel(app.UIAxes_tab2, 'Y')
            zlabel(app.UIAxes_tab2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout_axes);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.MenuPanel = uipanel(app.GridLayout_main);
            app.MenuPanel.Title = 'Menú';
            app.GridLayout_menu = uigridlayout(app.MenuPanel);
            app.btn_ej1 = uibutton(app.GridLayout_menu, 'state');
            app.btn_ej1.ValueChangedFcn = createCallbackFcn(app, @btn_1, true);
            app.btn_ej1.Text = 'Prob 1';
            app.btn_ej2 = uibutton(app.GridLayout_menu, 'state');
            app.btn_ej2.ValueChangedFcn = createCallbackFcn(app, @btn_2, true);
            app.btn_ej2.Text = 'Prob 2';
            app.btn_ej3 = uibutton(app.GridLayout_menu, 'state');
            app.btn_ej3.ValueChangedFcn = createCallbackFcn(app, @btn_3, true);
            app.btn_ej3.Text = 'Prob 3';
            app.btn_ej4 = uibutton(app.GridLayout_menu, 'state');
            app.btn_ej4.ValueChangedFcn = createCallbackFcn(app, @btn_4, true);
            app.btn_ej4.Text = 'Prob 4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end